classdef GUI_drone < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUIDroneUIFigure               matlab.ui.Figure
        DebugplotCheckBox              matlab.ui.control.CheckBox
        GuidancemodelButtonGroup       matlab.ui.container.ButtonGroup
        PathplannerButton              matlab.ui.control.RadioButton
        PathmanagerButton              matlab.ui.control.RadioButton
        PathfollowerButton_2           matlab.ui.control.RadioButton
        ControllerButton               matlab.ui.control.RadioButton
        SimulationtimesEditField       matlab.ui.control.NumericEditField
        SimulationtimesEditFieldLabel  matlab.ui.control.Label
        WindSlider_2                   matlab.ui.control.Slider
        AddgustsCheckBox               matlab.ui.control.CheckBox
        WindSlider                     matlab.ui.control.Slider
        MapparametersLabel             matlab.ui.control.Label
        SimulationparametersLabel_2    matlab.ui.control.Label
        DroneparametersLabel           matlab.ui.control.Label
        ShowmapCheckBox                matlab.ui.control.CheckBox
        AddwindCheckBox                matlab.ui.control.CheckBox
        PathplanningButtonGroup        matlab.ui.container.ButtonGroup
        StraightRRTButton              matlab.ui.control.RadioButton
        DubinButton                    matlab.ui.control.RadioButton
        FilletButton                   matlab.ui.control.RadioButton
        StartsimulationSwitch          matlab.ui.control.Switch
        StartsimulationSwitchLabel     matlab.ui.control.Label
        DronetypeButtonGroup           matlab.ui.container.ButtonGroup
        FixedwingButton                matlab.ui.control.RadioButton
        QuadcopterButton_2             matlab.ui.control.RadioButton
    end


    properties (Access = public)
        sim_time = 100;                 % default as 100 seconds
        simulation = 0;                 % 0 for controller, 1 for path follower, 2 for manager, 3 for planner, 4 for swarming, 5 for GUI
        drone_type = "quadcopter";                 
        nb_agents = 1;                  % number of drones in the simulation
        path_type = "dubins";           
        
        wind = false;                   % default as false
        wind_gust = false;              % default as false
        wind_level = 0;                 % default as 0
        wind_gust_level = 0;            % default as 0
        
        active_environment = true;      % default as true
        realistic_sim = true            % default as true
        
        orientation = 180;              % default as North
        
        debug_plot = false;             % default as false
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FilletButton.Enable = 'Off';
            app.DubinButton.Enable = 'Off';
            app.StraightRRTButton.Enable = 'Off';
            app.ShowmapCheckBox.Enable = 'Off';
        end

        % Selection changed function: DronetypeButtonGroup
        function DronetypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DronetypeButtonGroup.SelectedObject;
            switch selectedButton.Text  
                case 'Fixed-wing'
                    app.drone_type = "fixed_wing";
                case 'Quadcopter'
                    app.drone_type = "quadcopter";
            end
        end

        % Value changed function: StartsimulationSwitch
        function StartsimulationSwitchValueChanged(app, event)
            value = app.StartsimulationSwitch.Value;
            switch value
                case 'On'
%                     app.NumberofagentsSpinner.Enable = 'Off';
%                     app.QuadcopterButton_2.Enable = 'Off';
%                     app.FixedwingButton.Enable = 'Off';
%                     app.FilletButton.Enable = 'Off';
%                     app.DubinButton.Enable = 'Off';
%                     app.DubinCityButton.Enable = 'Off';
%                     app.StraightRRTButton.Enable = 'Off';
%                     app.CoverRRTDubinButton.Enable = 'Off';
                    switch app.simulation
                        case 0
                            example_controller();
                        case 1
                            example_path_follower();
                        case 2
                            example_path_manager();
                        case 3
                            example_path_planner();                         
                    end
                case 'Off'
%                     app.NumberofagentsSpinner.Enable = 'On';
%                     app.QuadcopterButton_2.Enable = 'On';
%                     app.FixedwingButton.Enable = 'On';
%                     app.FilletButton.Enable = 'On';
%                     app.DubinButton.Enable = 'On';
%                     app.DubinCityButton.Enable = 'On';
%                     app.StraightRRTButton.Enable = 'On';
%                     app.CoverRRTDubinButton.Enable = 'On';
%                     close all;
                    
            end
        end

        % Selection changed function: PathplanningButtonGroup
        function PathplanningButtonGroupSelectionChanged(app, event)
            selectedButton = app.PathplanningButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Fillet'
                    app.path_type = "fillet";
                case 'Dubin'
                    app.path_type = "dubins";
                case 'StraightRRT'
                    app.path_type = "straight_rrt";
            end
        end

        % Value changed function: AddwindCheckBox
        function AddwindCheckBoxValueChanged(app, event)
            app.wind = app.AddwindCheckBox.Value;
            if app.wind == false
                app.WindSlider.Enable = 'Off';
            else
                app.WindSlider.Enable = 'On';
            end
        end

        % Value changed function: ShowmapCheckBox
        function ShowmapCheckBoxValueChanged(app, event)
            app.active_environment = app.ShowmapCheckBox.Value;
        end

        % Value changed function: WindSlider
        function WindSliderValueChanged(app, event)
            app.wind_level = app.WindSlider.Value;
        end

        % Value changed function: AddgustsCheckBox
        function AddgustsCheckBoxValueChanged(app, event)
            app.wind_gust = app.AddgustsCheckBox.Value;
            if app.wind_gust == false
                app.WindSlider_2.Enable = 'Off';
            else
                app.WindSlider_2.Enable = 'On';
            end
        end

        % Value changed function: WindSlider_2
        function WindSlider_2ValueChanged(app, event)
            app.wind_gust_level = app.WindSlider_2.Value;
        end

        % Value changed function: SimulationtimesEditField
        function SimulationtimesEditFieldValueChanged(app, event)
            app.sim_time = app.SimulationtimesEditField.Value;
        end

        % Selection changed function: GuidancemodelButtonGroup
        function GuidancemodelButtonGroupSelectionChanged(app, event)
            selectedButton = app.GuidancemodelButtonGroup.SelectedObject;
            switch selectedButton.Text  
                case 'Controller'
                    app.simulation = 0;
                    app.FilletButton.Enable = 'Off';
                    app.DubinButton.Enable = 'Off';
                    app.StraightRRTButton.Enable = 'Off';
                    app.ShowmapCheckBox.Enable = 'Off';
                case 'Path follower'
                    app.simulation = 1;
                    app.FilletButton.Enable = 'Off';
                    app.DubinButton.Enable = 'Off';
                    app.StraightRRTButton.Enable = 'Off';
                    app.ShowmapCheckBox.Enable = 'On';
                case 'Path manager'
                    app.simulation = 2;
                    app.FilletButton.Enable = 'On';
                    app.DubinButton.Enable = 'On';
                    app.StraightRRTButton.Enable = 'Off';
                    app.path_type = 2;
                    app.ShowmapCheckBox.Enable = 'On';
                case 'Path planner'
                    app.simulation = 3;
                    app.FilletButton.Enable = 'Off';
                    app.DubinButton.Enable = 'Off';
                    app.StraightRRTButton.Enable = 'On';
                    app.path_type = 4;
                    app.ShowmapCheckBox.Enable = 'On';
                otherwise
                    app.simulation = 5;
                    app.FilletButton.Enable = 'Off';
                    app.DubinButton.Enable = 'Off';
                    app.StraightRRTButton.Enable = 'Off';
                    app.ShowmapCheckBox.Enable = 'On';
            end
        end

        % Value changed function: DebugplotCheckBox
        function DebugplotCheckBoxValueChanged(app, event)
            app.debug_plot = app.DebugplotCheckBox.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUIDroneUIFigure = uifigure('Visible', 'off');
            app.GUIDroneUIFigure.Name = 'GUI Drone';
            app.DronetypeButtonGroup = uibuttongroup(app.GUIDroneUIFigure);
            app.DronetypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DronetypeButtonGroupSelectionChanged, true);
            app.DronetypeButtonGroup.Title = 'Drone type';
            app.QuadcopterButton_2 = uiradiobutton(app.DronetypeButtonGroup);
            app.QuadcopterButton_2.Text = 'Quadcopter';
            app.QuadcopterButton_2.Value = true;
            app.FixedwingButton = uiradiobutton(app.DronetypeButtonGroup);
            app.FixedwingButton.Text = 'Fixed-wing';
            app.StartsimulationSwitchLabel = uilabel(app.GUIDroneUIFigure);
            app.StartsimulationSwitchLabel.Text = 'Start simulation';
            app.StartsimulationSwitch = uiswitch(app.GUIDroneUIFigure, 'slider');
            app.StartsimulationSwitch.ValueChangedFcn = createCallbackFcn(app, @StartsimulationSwitchValueChanged, true);
            app.PathplanningButtonGroup = uibuttongroup(app.GUIDroneUIFigure);
            app.PathplanningButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PathplanningButtonGroupSelectionChanged, true);
            app.PathplanningButtonGroup.Title = 'Path planning';
            app.FilletButton = uiradiobutton(app.PathplanningButtonGroup);
            app.FilletButton.Text = 'Fillet';
            app.DubinButton = uiradiobutton(app.PathplanningButtonGroup);
            app.DubinButton.Text = 'Dubin';
            app.DubinButton.Value = true;
            app.StraightRRTButton = uiradiobutton(app.PathplanningButtonGroup);
            app.StraightRRTButton.Text = 'StraightRRT';
            app.AddwindCheckBox = uicheckbox(app.GUIDroneUIFigure);
            app.AddwindCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddwindCheckBoxValueChanged, true);
            app.AddwindCheckBox.Text = 'Add wind';
            app.ShowmapCheckBox = uicheckbox(app.GUIDroneUIFigure);
            app.ShowmapCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowmapCheckBoxValueChanged, true);
            app.ShowmapCheckBox.Text = 'Show map';
            app.ShowmapCheckBox.Value = true;
            app.DroneparametersLabel = uilabel(app.GUIDroneUIFigure);
            app.DroneparametersLabel.Text = 'Drone parameters';
            app.SimulationparametersLabel_2 = uilabel(app.GUIDroneUIFigure);
            app.SimulationparametersLabel_2.Text = 'Simulation parameters';
            app.MapparametersLabel = uilabel(app.GUIDroneUIFigure);
            app.MapparametersLabel.Text = 'Map parameters';
            app.WindSlider = uislider(app.GUIDroneUIFigure);
            app.WindSlider.MajorTicks = [0 25 50 75 100];
            app.WindSlider.ValueChangedFcn = createCallbackFcn(app, @WindSliderValueChanged, true);
            app.WindSlider.Enable = 'off';
            app.AddgustsCheckBox = uicheckbox(app.GUIDroneUIFigure);
            app.AddgustsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AddgustsCheckBoxValueChanged, true);
            app.AddgustsCheckBox.Text = 'Add gusts';
            app.WindSlider_2 = uislider(app.GUIDroneUIFigure);
            app.WindSlider_2.MajorTicks = [0 25 50 75 100];
            app.WindSlider_2.ValueChangedFcn = createCallbackFcn(app, @WindSlider_2ValueChanged, true);
            app.WindSlider_2.Enable = 'off';
            app.SimulationtimesEditFieldLabel = uilabel(app.GUIDroneUIFigure);
            app.SimulationtimesEditFieldLabel.Text = 'Simulation time [s]';
            app.SimulationtimesEditField = uieditfield(app.GUIDroneUIFigure, 'numeric');
            app.SimulationtimesEditField.Limits = [0 Inf];
            app.SimulationtimesEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationtimesEditFieldValueChanged, true);
            app.SimulationtimesEditField.Value = 100;
            app.GuidancemodelButtonGroup = uibuttongroup(app.GUIDroneUIFigure);
            app.GuidancemodelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GuidancemodelButtonGroupSelectionChanged, true);
            app.GuidancemodelButtonGroup.Title = 'Guidance model';
            app.ControllerButton = uiradiobutton(app.GuidancemodelButtonGroup);
            app.ControllerButton.Text = 'Controller';
            app.ControllerButton.Value = true;
            app.PathfollowerButton_2 = uiradiobutton(app.GuidancemodelButtonGroup);
            app.PathfollowerButton_2.Text = 'Path follower';
            app.PathmanagerButton = uiradiobutton(app.GuidancemodelButtonGroup);
            app.PathmanagerButton.Text = 'Path manager';
            app.PathplannerButton = uiradiobutton(app.GuidancemodelButtonGroup);
            app.PathplannerButton.Text = 'Path planner';
            app.DebugplotCheckBox = uicheckbox(app.GUIDroneUIFigure);
            app.DebugplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @DebugplotCheckBoxValueChanged, true);
            app.DebugplotCheckBox.Text = 'Debug plot';
            % Show the figure after all components are created
            app.GUIDroneUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_drone

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUIDroneUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUIDroneUIFigure)
        end
    end
end