classdef GUI_swarm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GUISwarmUIFigure                matlab.ui.Figure
        ChanceOfSuccesSlider            matlab.ui.control.Slider
        ChanceOfSuccesSliderLabel       matlab.ui.control.Label
        SwarmingDefectButtonGroup       matlab.ui.container.ButtonGroup
        NoFrontRandomButton             matlab.ui.control.RadioButton
        NoFrontButton                   matlab.ui.control.RadioButton
        NoFrontNoBounceButton           matlab.ui.control.RadioButton
        DefaultButton                   matlab.ui.control.RadioButton
        ReferencespeedmsSlider          matlab.ui.control.Slider
        ReferencespeedmsSliderLabel     matlab.ui.control.Label
        InteragentdistancemSlider       matlab.ui.control.Slider
        InteragentdistancemSliderLabel  matlab.ui.control.Label
        DebugplotCheckBox               matlab.ui.control.CheckBox
        Orientation180NKnob             matlab.ui.control.Knob
        Orientation180NKnobLabel        matlab.ui.control.Label
        SimulationparametersLabel       matlab.ui.control.Label
        SimulationtimesEditField        matlab.ui.control.NumericEditField
        SimulationtimesEditFieldLabel   matlab.ui.control.Label
        SwarmingalgorithmButtonGroup    matlab.ui.container.ButtonGroup
        OlfatiSaberButton               matlab.ui.control.RadioButton
        VasarhelyiButton                matlab.ui.control.RadioButton
        MapparametersLabel              matlab.ui.control.Label
        SwarmparametersLabel            matlab.ui.control.Label
        DroneparametersLabel            matlab.ui.control.Label
        ShowmapCheckBox                 matlab.ui.control.CheckBox
        NumberofagentsSpinner           matlab.ui.control.Spinner
        NumberofagentsSpinnerLabel      matlab.ui.control.Label
        StartsimulationSwitch           matlab.ui.control.Switch
        StartsimulationSwitchLabel      matlab.ui.control.Label
        DronetypeButtonGroup            matlab.ui.container.ButtonGroup
        PointmassButton                 matlab.ui.control.RadioButton
        QuadcopterButton_2              matlab.ui.control.RadioButton
    end

    properties (Access = public)
        sim_time = 100;                 % default as 100 seconds
        simulation = 0;                 
        drone_type = "point_mass";                 
        nb_agents = 3;                  % number of drones in the simulation    
        
        wind = false;                   % default as false
        wind_gust = false;              % default as false
        wind_level = 0;                 % default as 0
        wind_gust_level = 0;            % default as 0
        
        active_environment = true;      % default as true
        
        swarming_algo = "vasarhelyi";   % default as Vasarhelyi
        swarming_defect = "default";
        front_sensor_failure_chance = 1;
        orientation = 180;              % default as North
        d_ref = 10                      % default as 10  
        v_ref = 6                       % default as 6
        
        debug_plot = false;             % default as false
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.VasarhelyiButton.Enable = 'On';
            app.OlfatiSaberButton.Enable = 'On';
            app.NumberofagentsSpinner.Enable = 'On';
            app.nb_agents = 3;
            app.ShowmapCheckBox.Enable = 'On';
            app.Orientation180NKnob.Enable = 'On';
            app.InteragentdistancemSlider.Enable = 'On';
            app.ReferencespeedmsSlider.Enable = 'On';
        end

        % Selection changed function: DronetypeButtonGroup
        function DronetypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.DronetypeButtonGroup.SelectedObject;
            switch selectedButton.Text  
                case 'Quadcopter'
                    app.drone_type = "quadcopter";
                case 'Point-mass'
                    app.drone_type = "point_mass";
            end
        end

        % Value changed function: StartsimulationSwitch
        function StartsimulationSwitchValueChanged(app, event)
            value = app.StartsimulationSwitch.Value;
            switch value
                case 'On'
%                     app.NumberofagentsSpinner.Enable = 'Off';
%                     app.QuadcopterButton_2.Enable = 'Off';
%                     app.FixedwingButton.Enable = 'Off';
%                     app.FilletButton.Enable = 'Off';
%                     app.DubinButton.Enable = 'Off';
%                     app.DubinCityButton.Enable = 'Off';
%                     app.StraightRRTButton.Enable = 'Off';
%                     app.CoverRRTDubinButton.Enable = 'Off';
                    switch app.swarming_algo
                        case "vasarhelyi"
                            example_vasarhelyi(); 
                        case "olfati_saber"
                            example_olfati_saber();
                    end
                case 'Off'
%                     app.NumberofagentsSpinner.Enable = 'On';
%                     app.QuadcopterButton_2.Enable = 'On';
%                     app.FixedwingButton.Enable = 'On';
%                     app.FilletButton.Enable = 'On';
%                     app.DubinButton.Enable = 'On';
%                     app.DubinCityButton.Enable = 'On';
%                     app.StraightRRTButton.Enable = 'On';
%                     app.CoverRRTDubinButton.Enable = 'On';
%                     close all;
                    
            end
        end

        % Value changed function: NumberofagentsSpinner
        function NumberofagentsSpinnerValueChanged(app, event)
            app.nb_agents = app.NumberofagentsSpinner.Value;
        end

        % Callback function
        function AddwindCheckBoxValueChanged(app, event)
            app.wind = app.AddwindCheckBox.Value;
            if app.wind == false
                app.WindSlider.Enable = 'Off';
            else
                app.WindSlider.Enable = 'On';
            end
        end

        % Value changed function: ShowmapCheckBox
        function ShowmapCheckBoxValueChanged(app, event)
            app.active_environment = app.ShowmapCheckBox.Value;
        end

        % Callback function
        function WindSliderValueChanged(app, event)
            app.wind_level = app.WindSlider.Value;
        end

        % Callback function
        function AddgustsCheckBoxValueChanged(app, event)
            app.wind_gust = app.AddgustsCheckBox.Value;
            if app.wind_gust == false
                app.WindSlider_2.Enable = 'Off';
            else
                app.WindSlider_2.Enable = 'On';
            end
        end

        % Callback function
        function WindSlider_2ValueChanged(app, event)
            app.wind_gust_level = app.WindSlider_2.Value;
        end

        % Selection changed function: SwarmingalgorithmButtonGroup
        function SwarmingalgorithmButtonGroupSelectionChanged(app, event)
            selectedButton = app.SwarmingalgorithmButtonGroup.SelectedObject;
            switch selectedButton.Text  
                case 'Vasarhelyi'
                    app.swarming_algo = "vasarhelyi";
                case 'Olfati-Saber'
                    app.swarming_algo = "olfati_saber";
            end
        end

        % Value changed function: SimulationtimesEditField
        function SimulationtimesEditFieldValueChanged(app, event)
            app.sim_time = app.SimulationtimesEditField.Value;
        end

        % Callback function
        function SimulationButtonGroupSelectionChanged(app, event)
            selectedButton = app.SimulationButtonGroup.SelectedObject;
            switch selectedButton.Text  
                case 'Vasarhelyi'
                    app.simulation = 0;
                    app.VasarhelyiButton.Enable = 'On';
                    app.OlfatiSaberButton.Enable = 'On';
                    app.NumberofagentsSpinner.Enable = 'On';
                    app.nb_agents = app.NumberofagentsSpinner.Value;
                    app.ShowmapCheckBox.Enable = 'On';
                    app.Orientation180NKnob.Enable = 'On';
                    app.d_ref = app.InteragentdistancemSlider.Value;
                    app.v_ref = app.ReferencespeedmsSlider.Value;
                case 'Olfati-Saber'
                    app.simulation = 1;
                    app.VasarhelyiButton.Enable = 'Off';
                    app.OlfatiSaberButton.Enable = 'Off';
                    app.NumberofagentsSpinner.Enable = 'On';
                    app.nb_agents = app.NumberofagentsSpinner.Value;
                    app.ShowmapCheckBox.Enable = 'On';
                    app.Orientation180NKnob.Enable = 'Off';
                    app.d_ref = app.InteragentdistancemSlider.Value;
                    app.v_ref = app.ReferencespeedmsSlider.Value;
            end
        end

        % Value changed function: Orientation180NKnob
        function Orientation180NKnobValueChanged(app, event)
            app.orientation = app.Orientation180NKnob.Value;
        end

        % Value changing function: Orientation180NKnob
        function Orientation180NKnobValueChanging(app, event)
            app.orientation = event.Value;
        end

        % Value changed function: DebugplotCheckBox
        function DebugplotCheckBoxValueChanged(app, event)
            app.debug_plot = app.DebugplotCheckBox.Value;
        end

        % Value changed function: InteragentdistancemSlider
        function InteragentdistancemSliderValueChanged(app, event)
            app.d_ref = app.InteragentdistancemSlider.Value;
        end

        % Value changed function: ReferencespeedmsSlider
        function ReferencespeedmsSliderValueChanged(app, event)
            app.v_ref = app.ReferencespeedmsSlider.Value;
        end

        % Selection changed function: SwarmingDefectButtonGroup
        function SwarmingDefectButtonGroupSelectionChanged(app, event)
            selectedButton = app.SwarmingDefectButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "Default"
                    app.swarming_defect = "default";
                case "No Front No Bounce"
                    app.swarming_defect = "no_front_no_bounce";
                case "No Front"
                    app.swarming_defect = "no_front";
                case "No Front Random"
                    app.swarming_defect = "no_front_random";
            end
        end

        % Value changed function: ChanceOfSuccesSlider
        function ChanceOfSuccesSliderValueChanged(app, event)
            app.front_sensor_failure_chance = app.ChanceOfSuccesSlider.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GUISwarmUIFigure = uifigure('Visible', 'off');
            app.GUISwarmUIFigure.Name = 'GUI Swarm';
            app.DronetypeButtonGroup = uibuttongroup(app.GUISwarmUIFigure);
            app.DronetypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DronetypeButtonGroupSelectionChanged, true);
            app.DronetypeButtonGroup.Title = 'Drone type';
            app.QuadcopterButton_2 = uiradiobutton(app.DronetypeButtonGroup);
            app.QuadcopterButton_2.Text = 'Quadcopter';
            app.PointmassButton = uiradiobutton(app.DronetypeButtonGroup);
            app.PointmassButton.Text = 'Point-mass';
            app.PointmassButton.Value = true;
            app.StartsimulationSwitchLabel = uilabel(app.GUISwarmUIFigure);
            app.StartsimulationSwitchLabel.Text = 'Start simulation';
            app.StartsimulationSwitch = uiswitch(app.GUISwarmUIFigure, 'slider');
            app.StartsimulationSwitch.ValueChangedFcn = createCallbackFcn(app, @StartsimulationSwitchValueChanged, true);
            app.NumberofagentsSpinnerLabel = uilabel(app.GUISwarmUIFigure);
            app.NumberofagentsSpinnerLabel.Text = 'Number of agents';
            app.NumberofagentsSpinner = uispinner(app.GUISwarmUIFigure);
            app.NumberofagentsSpinner.Limits = [1 50];
            app.NumberofagentsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofagentsSpinnerValueChanged, true);
            app.NumberofagentsSpinner.Value = 3;
            app.ShowmapCheckBox = uicheckbox(app.GUISwarmUIFigure);
            app.ShowmapCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowmapCheckBoxValueChanged, true);
            app.ShowmapCheckBox.Text = 'Show map';
            app.ShowmapCheckBox.Value = true;
            app.DroneparametersLabel = uilabel(app.GUISwarmUIFigure);
            app.DroneparametersLabel.Text = 'Drone parameters';
            app.SwarmparametersLabel = uilabel(app.GUISwarmUIFigure);
            app.SwarmparametersLabel.Text = 'Swarm parameters';
            app.MapparametersLabel = uilabel(app.GUISwarmUIFigure);
            app.MapparametersLabel.Text = 'Map parameters';
            app.SwarmingalgorithmButtonGroup = uibuttongroup(app.GUISwarmUIFigure);
            app.SwarmingalgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SwarmingalgorithmButtonGroupSelectionChanged, true);
            app.SwarmingalgorithmButtonGroup.Title = 'Swarming algorithm';
            app.VasarhelyiButton = uiradiobutton(app.SwarmingalgorithmButtonGroup);
            app.VasarhelyiButton.Text = 'Vasarhelyi';
            app.VasarhelyiButton.Value = true;
            app.OlfatiSaberButton = uiradiobutton(app.SwarmingalgorithmButtonGroup);
            app.OlfatiSaberButton.Text = 'Olfati-Saber';
            app.SimulationtimesEditFieldLabel = uilabel(app.GUISwarmUIFigure);
            app.SimulationtimesEditFieldLabel.Text = 'Simulation time [s]';
            app.SimulationtimesEditField = uieditfield(app.GUISwarmUIFigure, 'numeric');
            app.SimulationtimesEditField.Limits = [0 Inf];
            app.SimulationtimesEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationtimesEditFieldValueChanged, true);
            app.SimulationtimesEditField.Value = 100;
            app.SimulationparametersLabel = uilabel(app.GUISwarmUIFigure);
            app.SimulationparametersLabel.Text = 'Simulation parameters';
            app.Orientation180NKnobLabel = uilabel(app.GUISwarmUIFigure);
            app.Orientation180NKnobLabel.Text = 'Orientation (180=N)';
            app.Orientation180NKnob = uiknob(app.GUISwarmUIFigure, 'continuous');
            app.Orientation180NKnob.Limits = [0 360];
            app.Orientation180NKnob.MajorTicks = [0 90 180 270 360];
            app.Orientation180NKnob.MajorTickLabels = {'South', 'West', 'North', 'East', 'South'};
            app.Orientation180NKnob.ValueChangedFcn = createCallbackFcn(app, @Orientation180NKnobValueChanged, true);
            app.Orientation180NKnob.ValueChangingFcn = createCallbackFcn(app, @Orientation180NKnobValueChanging, true);
            app.Orientation180NKnob.Value = 180;
            app.DebugplotCheckBox = uicheckbox(app.GUISwarmUIFigure);
            app.DebugplotCheckBox.ValueChangedFcn = createCallbackFcn(app, @DebugplotCheckBoxValueChanged, true);
            app.DebugplotCheckBox.Text = 'Debug plot';
            app.InteragentdistancemSliderLabel = uilabel(app.GUISwarmUIFigure);
            app.InteragentdistancemSliderLabel.Text = 'Inter-agent distance [m]';
            app.InteragentdistancemSlider = uislider(app.GUISwarmUIFigure);
            app.InteragentdistancemSlider.Limits = [5 15];
            app.InteragentdistancemSlider.MajorTicks = [5 7 10 12.5 15];
            app.InteragentdistancemSlider.MajorTickLabels = {'5', '7,5', '10', '12.5', '15'};
            app.InteragentdistancemSlider.ValueChangedFcn = createCallbackFcn(app, @InteragentdistancemSliderValueChanged, true);
            app.InteragentdistancemSlider.Value = 10;
            app.ReferencespeedmsSliderLabel = uilabel(app.GUISwarmUIFigure);
            app.ReferencespeedmsSliderLabel.Text = 'Reference speed [m/s]';
            app.ReferencespeedmsSlider = uislider(app.GUISwarmUIFigure);
            app.ReferencespeedmsSlider.Limits = [2 8];
            app.ReferencespeedmsSlider.MajorTicks = [2 4 6 8 10];
            app.ReferencespeedmsSlider.ValueChangedFcn = createCallbackFcn(app, @ReferencespeedmsSliderValueChanged, true);
            app.ReferencespeedmsSlider.Value = 6;
            app.SwarmingDefectButtonGroup = uibuttongroup(app.GUISwarmUIFigure);
            app.SwarmingDefectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SwarmingDefectButtonGroupSelectionChanged, true);
            app.SwarmingDefectButtonGroup.Title = 'Swarming Defect';
            app.DefaultButton = uiradiobutton(app.SwarmingDefectButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.DefaultButton.Value = true;
            app.NoFrontNoBounceButton = uiradiobutton(app.SwarmingDefectButtonGroup);
            app.NoFrontNoBounceButton.Text = 'No Front No Bounce';
            app.NoFrontButton = uiradiobutton(app.SwarmingDefectButtonGroup);
            app.NoFrontButton.Text = 'No Front';
            app.NoFrontRandomButton = uiradiobutton(app.SwarmingDefectButtonGroup);
            app.NoFrontRandomButton.Text = 'No Front Random';
            app.ChanceOfSuccesSliderLabel = uilabel(app.GUISwarmUIFigure);
            app.ChanceOfSuccesSliderLabel.Text = 'Chance Of Succes';
            app.ChanceOfSuccesSlider = uislider(app.GUISwarmUIFigure);
            app.ChanceOfSuccesSlider.Limits = [0 1];
            app.ChanceOfSuccesSlider.ValueChangedFcn = createCallbackFcn(app, @ChanceOfSuccesSliderValueChanged, true);
            app.ChanceOfSuccesSlider.Value = 1;
            % Show the figure after all components are created
            app.GUISwarmUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_swarm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GUISwarmUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GUISwarmUIFigure)
        end
    end
end